classdef Moodle_Texer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MoodleTextArea         matlab.ui.control.TextArea
        MoodleTextAreaLabel    matlab.ui.control.Label
        MarkdownTextArea       matlab.ui.control.TextArea
        MarkdownTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MD % Description
        Moodle % Description
    end
    
    methods (Access = private)
        
        function Moodle = convert(~,MD_txt)
            Moodle = {};
        
            i = 1;
            j = 1;
            while i <= length(MD_txt)
        
        
                if MD_txt(i)=="$$"
                    
                    Moodle_latex = " \( ";
                    while true
        
                        i = i + 1;
        
                        if MD_txt(i) == "$$" || i >=length(MD_txt)
                            break
                        else
                            Moodle_latex = Moodle_latex + MD_txt(i);
                        end
                    end
                    Moodle_latex = Moodle_latex + " \) ";
                    i = i + 1;
        
                    Moodle{j} = char(Moodle_latex);
                    
                else
                    MD_line = char(MD_txt(i));
                    k = strfind(MD_line,"$");
                    Bi = k(1:2:end);
                    Ei = k(2:2:end);
                    Pair = 1:length(Bi);
                    
                    if ~isempty(Pair)
                        Moodle_line = MD_line(1:Bi(1)-1);
                        for p = Pair
                            Moodle_line = Moodle_line + ...
                                          " \( " + MD_line(Bi(p)+1:Ei(p)-1) + ...
                                          " \) ";
                            if p < length(Pair)
                                Moodle_line = Moodle_line + ...
                                              MD_line(Ei(p)+1:Bi(p+1)-1);
                            else
                                Moodle_line = Moodle_line + ...
                                              MD_line(Ei(p)+1:end);
                            end
                        end
                    else
                        Moodle_line = MD_line;
                    end
        
                    Moodle{j} = char(Moodle_line);
                    i = i + 1;
                end
                
                j = j + 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: MarkdownTextArea
        function MarkdownTextAreaValueChanging(app, event)
            app.MD = event.Value;
            try
                app.MoodleTextArea.Value = convert(app,app.MD);
                app.MoodleTextArea.BackgroundColor = "w";
            catch
                app.MoodleTextArea.BackgroundColor = "r";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MarkdownTextAreaLabel = uilabel(app.UIFigure);
            app.MarkdownTextAreaLabel.Text = 'Markdown';
            app.MarkdownTextArea = uitextarea(app.UIFigure);
            app.MarkdownTextArea.ValueChangingFcn = createCallbackFcn(app, @MarkdownTextAreaValueChanging, true);
            app.MoodleTextAreaLabel = uilabel(app.UIFigure);
            app.MoodleTextAreaLabel.Text = 'Moodle';
            app.MoodleTextArea = uitextarea(app.UIFigure);
            app.MoodleTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Moodle_Texer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end